classdef IPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadButton                matlab.ui.control.Button
        PowerindexEditField       matlab.ui.control.NumericEditField
        PowerindexEditFieldLabel  matlab.ui.control.Label
        UpdateGraphButton         matlab.ui.control.Button
        TempindexEditField        matlab.ui.control.NumericEditField
        TempindexEditFieldLabel   matlab.ui.control.Label
        KIDEditField              matlab.ui.control.NumericEditField
        KIDEditFieldLabel         matlab.ui.control.Label
        plotsetupCheckBox         matlab.ui.control.CheckBox
        plotCBCheckBox            matlab.ui.control.CheckBox
        SWplotTLSCheckBox         matlab.ui.control.CheckBox
        C_GRSlider                matlab.ui.control.Slider
        C_GRSliderLabel           matlab.ui.control.Label
        Tau_qp10xSlider           matlab.ui.control.Slider
        Tau_qp10xSliderLabel      matlab.ui.control.Label
        gammaSlider               matlab.ui.control.Slider
        gammaLabel                matlab.ui.control.Label
        C_TLSSlider               matlab.ui.control.Slider
        C_TLSSliderLabel          matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateGraphButton
        function UpdateGraphButtonPushed(app, event)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
%% Part 1: GR-noise fit. 
% This script does the main data analysis to how the interplay is between
% the TLS-noise and the GR-noise when we vary the temperature
% Author: Sietse de Boer
klier % Clearing...
% Importing Data
%
addpath('..\subroutines')
ChipInfo_path = ['..' filesep '..' filesep ]; %root path where data is, one higher than the scripts
FFTsubsubdir=['Data_LT254_Sietse' filesep 'LT254_Sietse_Chip11' filesep 'Noise_vs_T' filesep 'FFT' filesep '2D'];% This is where the
load([ChipInfo_path FFTsubsubdir filesep 'NOISE_2D.mat'])
freq = NOISE(1).FFTnoise{1,1}(:,1);
% User variables

% Variable (Change at your own risk!)

% TLS fit range 
TLSfitmin = 0.8;% [Hz]
TLSfitmax = 30;% [Hz]
[~,TLSf_min_i] = min(abs(freq-TLSfitmin)); % This gives the min index
[~,TLSf_max_i] = min(abs(freq-TLSfitmax)); % This gives the max index
C_v_TLS_0 = [(10^-15) 1.5];% CTLS at 1 Hz , power with what  the power law decreases. TLS ~0.5
C_v0_dBlog = [-150 1.5];% CTLS at 1 Hz , power with what  the power law decreases. TLS ~0.5
%TLS_lb = [10^-25 0.1];
%TLS_ub = [10^-8 2];
% Combined fit range: GR model + TLS noise model = approx GR model at high
% freq
CBfitmin = 1000;% [Hz]
CBfitmax = 150000;%  [Hz]
[~,CBf_min_i] = min(abs(freq-CBfitmin)); % "
[~,CBf_max_i] = min(abs(freq-CBfitmax)); % "
C_v_CB_0 = [(10^-18) 1.591549430918954e-06];% CTLS at 1 Hz , power with what  the power law decreases. TLS ~0.5

fguess = 2000; % [Hz] initial guess for the intersection point of the TLS and GR noise. 

% CB_lb = [10^-20 0.000000000000001];
% CB_ub = [10^-15 1];
CB_lb = [10^-20 (1/(2*pi*CBfitmax))];
CB_ub = [10^-15 (1/(2*pi*CBfitmin))];

%--Choosing your favorite KIDS,temperature and Reaout power(Moar Powa Baby)
kidn_iter = 2; %[Index] Select KID.
Tbath_iter = 8; %[Index] Number of Temperatures T_bath. Likely loop between 1:14;
SW_powerselector = 1 ;% choose power yourself or select Popt from file
power_selector = 7 ;% The index of the power that you want from low to high
%--End Choosing------------------------------------------------------------

%--Readout power selection ------------------------------------------------
if SW_powerselector % 1 case
f_power_iter = @(kidn)IndexPsort{kidn,1}(power_selector);
else % else
f_power_iter = @(kidn)IndexPopt(1,kidn);
end 
%-/Readout power selection -------------------------------------------------
tot_combi = length(concat_v(kidn_iter,IndexPsort,1));
f = gobjects(tot_combi,1);
ax = gobjects(tot_combi,1);
% Setup noise estimate
setup_noise_eval_freq = 350000;% [Hz]
[~,Snoise_i] = min(abs(freq-setup_noise_eval_freq)); % "

% Define fitting model 
% Here I will define the models that we will be using to fit the data. 
% This we will use to obtain a non-linear fit of the data. This gives heavy
% weight on the parts of the graph that are high on log log plot since here
% the difference is the biggest. 
Model_oneoverf = @(C_v,fdata)C_v(1).*power(fdata,-1*C_v(2)); %Model we use to fit C_v is the constants vector that we are trying to find.
Model_GR = @(C_v,fdata) C_v(1)./((1+power((2.*pi.*fdata.*C_v(2)),2).*(1))); %Model we use to fit C_v is the constants vector that we are trying to find.
Model_CB = @(C_v,fdata) C_v(1)./((1+power((2.*pi.*fdata.*C_v(2)),2).*(1)))+ Scurr_noise_level;
%Model_CB = @(C_v,fdata) C_TLS_corr.*power(fdata,-1*gamma_TLS)+C_v(1)./(1+power((2.*pi.*fdata.*C_v(2)),2)); %Model we use to fit C_v is the constants vector that we are trying to find.

%These models below are meant to be used in log space. Maybe later f = 10^x
% --> Later..
Model_oneoverf_dBlog = @(C_v,xdata)10*log10(C_v(1)) -10.*C_v(2).*xdata; %Model we use to fit C_v is the constants vector that we are trying to find.
%Model_GR_logspace = @(C_v,xdata) 10*log10(C(1))+10.*log10(1./(1+power((2.*pi.*power(10,x).*C(2)),2))); %Model we use to fit C_v is the constants vector that we are trying to find.

%Linear helper line
Model_line = @(C_v,xdata)C_v(1).*xdata+C_v(2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.C_TLSSliderLabel = uilabel(app.UIFigure);
            app.C_TLSSliderLabel.Text = 'C_TLS';
            app.C_TLSSlider = uislider(app.UIFigure);
            app.C_TLSSlider.Limits = [-160 -130];
            app.C_TLSSlider.Value = -130;
            app.gammaLabel = uilabel(app.UIFigure);
            app.gammaLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.UIFigure);
            app.gammaSlider.Limits = [0.5 1.5];
            app.gammaSlider.Value = 0.5;
            app.Tau_qp10xSliderLabel = uilabel(app.UIFigure);
            app.Tau_qp10xSliderLabel.Text = 'Tau_qp(10^x)';
            app.Tau_qp10xSlider = uislider(app.UIFigure);
            app.Tau_qp10xSlider.Limits = [-6 -4];
            app.Tau_qp10xSlider.Value = -4;
            app.C_GRSliderLabel = uilabel(app.UIFigure);
            app.C_GRSliderLabel.Text = 'C_GR';
            app.C_GRSlider = uislider(app.UIFigure);
            app.C_GRSlider.Limits = [-180 -150];
            app.C_GRSlider.Value = -150;
            app.SWplotTLSCheckBox = uicheckbox(app.UIFigure);
            app.SWplotTLSCheckBox.Text = 'SW plot TLS';
            app.plotCBCheckBox = uicheckbox(app.UIFigure);
            app.plotCBCheckBox.Text = 'plot CB';
            app.plotsetupCheckBox = uicheckbox(app.UIFigure);
            app.plotsetupCheckBox.Text = 'plot setup';
            app.KIDEditFieldLabel = uilabel(app.UIFigure);
            app.KIDEditFieldLabel.Text = 'KID #';
            app.KIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempindexEditFieldLabel = uilabel(app.UIFigure);
            app.TempindexEditFieldLabel.Text = 'Temp index';
            app.TempindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateGraphButton = uibutton(app.UIFigure, 'push');
            app.UpdateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGraphButtonPushed, true);
            app.UpdateGraphButton.Text = 'Update Graph';
            app.PowerindexEditFieldLabel = uilabel(app.UIFigure);
            app.PowerindexEditFieldLabel.Text = 'Power index';
            app.PowerindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end